classdef CVdashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TimeScope_4         matlab.ui.scope.TimeScope
        TimeScope_3         matlab.ui.scope.TimeScope
        TimeScope_2         matlab.ui.scope.TimeScope
        TimeScope           matlab.ui.scope.TimeScope
        Slider4Label        matlab.ui.control.Label
        Slider4             matlab.ui.control.Slider
        Slider3Label        matlab.ui.control.Label
        Slider3             matlab.ui.control.Slider
        SimulationProgress  simulink.ui.control.SimulationProgress
        SimulationControls  simulink.ui.control.SimulationControls
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationControls = uisimcontrols(app.UIFigure);
            app.SimulationControls.Simulation = app.Simulation;
            app.SimulationProgress = uisimprogress(app.UIFigure);
            app.SimulationProgress.Simulation = app.Simulation;
            app.Slider3 = uislider(app.UIFigure);
            app.Slider3.Limits = [0 2000];
            app.Slider3Label = uilabel(app.UIFigure);
            app.Slider3Label.Text = 'CV1';
            app.Slider4 = uislider(app.UIFigure);
            app.Slider4.Limits = [0 2000];
            app.Slider4Label = uilabel(app.UIFigure);
            app.Slider4Label.Text = 'CV2';
            app.TimeScope = uitimescope(app.UIFigure);
            app.TimeScope.LegendNames = {'Cell 1'};
            app.TimeScope.Title = 'Cell 1';
            app.TimeScope.YLabel = 'Amplitude';
            app.TimeScope_2 = uitimescope(app.UIFigure);
            app.TimeScope_2.LegendNames = {'Heart/Demux2:2'};
            app.TimeScope_2.Title = 'Slow Cell';
            app.TimeScope_2.YLabel = 'Amplitude';
            app.TimeScope_3 = uitimescope(app.UIFigure);
            app.TimeScope_3.LegendNames = {'Heart/Demux1:2'};
            app.TimeScope_3.Title = 'Cell 2';
            app.TimeScope_3.YLabel = 'Amplitude';
            app.TimeScope_4 = uitimescope(app.UIFigure);
            app.TimeScope_4.LegendNames = {'Heart/Demux3:2'};
            app.TimeScope_4.Title = 'Fast Cell';
            app.TimeScope_4.YLabel = 'Amplitude';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Slider3, 'Value', app.Simulation.TunableVariables, 'CV1');
            bind(app.Slider4, 'Value', app.Simulation.TunableVariables, 'CV2');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Heart/Demux:2'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Heart/Demux2:2'], app.TimeScope_2);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Heart/Demux1:2'], app.TimeScope_3);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Heart/Demux3:2'], app.TimeScope_4);
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CVdashboard

            % Associate the Simulink Model
            app.Simulation = simulation('CLSfixedExample3');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end