classdef dashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TimeScope           matlab.ui.scope.TimeScope
        Slider2             matlab.ui.control.Slider
        Slider2Label        matlab.ui.control.Label
        Slider_7            matlab.ui.control.Slider
        Slider_7Label       matlab.ui.control.Label
        Slider_6            matlab.ui.control.Slider
        Slider_6Label       matlab.ui.control.Label
        Slider_5            matlab.ui.control.Slider
        Slider_5Label       matlab.ui.control.Label
        Slider_4            matlab.ui.control.Slider
        Slider_4Label       matlab.ui.control.Label
        Slider_3            matlab.ui.control.Slider
        Slider_3Label       matlab.ui.control.Label
        SimulationProgress  simulink.ui.control.SimulationProgress
        SimulationControls  simulink.ui.control.SimulationControls
        Slider_2            matlab.ui.control.Slider
        Slider_2Label       matlab.ui.control.Label
        Slider              matlab.ui.control.Slider
        SliderLabel         matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'BCL';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.Value = 0.8;
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = 'SD';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 0.01];
            app.Slider_2.Value = 0.002;
            app.SimulationControls = uisimcontrols(app.UIFigure);
            app.SimulationControls.Simulation = app.Simulation;
            app.SimulationProgress = uisimprogress(app.UIFigure);
            app.SimulationProgress.Simulation = app.Simulation;
            app.Slider_3Label = uilabel(app.UIFigure);
            app.Slider_3Label.Text = 'f1';
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [0 1];
            app.Slider_3.Value = 0.1;
            app.Slider_4Label = uilabel(app.UIFigure);
            app.Slider_4Label.Text = 'f2';
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.Limits = [0 1];
            app.Slider_4.Value = 0.25;
            app.Slider_5Label = uilabel(app.UIFigure);
            app.Slider_5Label.Text = 'sig1';
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.Limits = [0 0.01];
            app.Slider_5.Value = 0.0035;
            app.Slider_6Label = uilabel(app.UIFigure);
            app.Slider_6Label.Text = 'sig2';
            app.Slider_6 = uislider(app.UIFigure);
            app.Slider_6.Limits = [0 0.01];
            app.Slider_6.Value = 0.007;
            app.Slider_7Label = uilabel(app.UIFigure);
            app.Slider_7Label.Text = 'VT';
            app.Slider_7 = uislider(app.UIFigure);
            app.Slider_7.Value = 23.81;
            app.Slider2Label = uilabel(app.UIFigure);
            app.Slider2Label.Text = 'VR';
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.Value = 47.94;
            app.TimeScope = uitimescope(app.UIFigure);
            app.TimeScope.Title = 'Title';
            app.TimeScope.YLabel = 'Amplitude';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Slider, 'Value', app.Simulation.TunableVariables, 'BCL');
            bind(app.Slider_3, 'Value', app.Simulation.TunableVariables, 'f1');
            bind(app.Slider_4, 'Value', app.Simulation.TunableVariables, 'f2');
            bind(app.Slider_2, 'Value', app.Simulation.TunableVariables, 'SD');
            bind(app.Slider_5, 'Value', app.Simulation.TunableVariables, 'sig1');
            bind(app.Slider_6, 'Value', app.Simulation.TunableVariables, 'sig2');
            bind(app.Slider2, 'Value', app.Simulation.TunableVariables, 'VR');
            bind(app.Slider_7, 'Value', app.Simulation.TunableVariables, 'VT');
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dashboard

            % Associate the Simulink Model
            app.Simulation = simulation('CLSfixedExample1');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end